classdef roskam < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        save_new                     matlab.ui.control.Button
        filename                     matlab.ui.control.Label
        load                         matlab.ui.control.Button
        save                         matlab.ui.control.Button
        TabGroup                     matlab.ui.container.TabGroup
        resumen                      matlab.ui.container.Tab
        Label                        matlab.ui.control.Label
        tipo                         matlab.ui.control.DropDown
        TipodeaeronaveDropDownLabel  matlab.ui.control.Label
        fc                           matlab.ui.container.Tab
        Tabla21Button                matlab.ui.control.Button
        W98                          matlab.ui.control.EditField
        W8Label_2                    matlab.ui.control.Label
        TabGroup2                    matlab.ui.container.TabGroup
        AscensoTab                   matlab.ui.container.Tab
        Tabla22Button1               matlab.ui.control.Button
        n_p_cl                       matlab.ui.control.EditField
        W4Label_7                    matlab.ui.control.Label
        c_cl                         matlab.ui.control.EditField
        W4Label_5                    matlab.ui.control.Label
        W4Label_4                    matlab.ui.control.Label
        V_cl                         matlab.ui.control.EditField
        LD_cl                        matlab.ui.control.EditField
        W4Label_3                    matlab.ui.control.Label
        E_cl                         matlab.ui.control.EditField
        W4Label_2                    matlab.ui.control.Label
        CruceroTab                   matlab.ui.container.Tab
        Tabla22Button2               matlab.ui.control.Button
        V_cr                         matlab.ui.control.EditField
        W4Label_12                   matlab.ui.control.Label
        LD_cr                        matlab.ui.control.EditField
        W4Label_11                   matlab.ui.control.Label
        n_p_cr                       matlab.ui.control.EditField
        W4Label_10                   matlab.ui.control.Label
        c_cr                         matlab.ui.control.EditField
        W4Label_9                    matlab.ui.control.Label
        R_cr                         matlab.ui.control.EditField
        W4Label_8                    matlab.ui.control.Label
        LoiterTab                    matlab.ui.container.Tab
        Tabla22Button3               matlab.ui.control.Button
        E_lo                         matlab.ui.control.EditField
        W4Label_17                   matlab.ui.control.Label
        V_lo                         matlab.ui.control.EditField
        W4Label_16                   matlab.ui.control.Label
        n_p_lo                       matlab.ui.control.EditField
        W4Label_15                   matlab.ui.control.Label
        c_lo                         matlab.ui.control.EditField
        W4Label_14                   matlab.ui.control.Label
        LD_lo                        matlab.ui.control.EditField
        W4Label_13                   matlab.ui.control.Label
        m_ff                         matlab.ui.control.EditField
        M_ffEditFieldLabel           matlab.ui.control.Label
        W87                          matlab.ui.control.EditField
        W8Label                      matlab.ui.control.Label
        W76                          matlab.ui.control.EditField
        W7Label                      matlab.ui.control.Label
        W65                          matlab.ui.control.EditField
        W6Label                      matlab.ui.control.Label
        W54                          matlab.ui.control.EditField
        W5Label                      matlab.ui.control.Label
        W43                          matlab.ui.control.EditField
        W4Label                      matlab.ui.control.Label
        W32                          matlab.ui.control.EditField
        W3Label                      matlab.ui.control.Label
        W21                          matlab.ui.control.EditField
        W2Label                      matlab.ui.control.Label
        W1TO                         matlab.ui.control.EditField
        W_1Label                     matlab.ui.control.Label
        emptyweight                  matlab.ui.container.Tab
        eqerror                      matlab.ui.control.Label
        WEtarget                     matlab.ui.control.EditField
        W5Label_13                   matlab.ui.control.Label
        WError                       matlab.ui.control.EditField
        W5Label_12                   matlab.ui.control.Label
        WPlotButton                  matlab.ui.control.Button
        W_to                         matlab.ui.control.EditField
        W5Label_11                   matlab.ui.control.Label
        we_eq                        matlab.ui.control.Label
        WE                           matlab.ui.control.EditField
        W5Label_10                   matlab.ui.control.Label
        Wcrew                        matlab.ui.control.EditField
        W5Label_9                    matlab.ui.control.Label
        Wpl                          matlab.ui.control.EditField
        W5Label_8                    matlab.ui.control.Label
        Wtfo                         matlab.ui.control.EditField
        W5Label_7                    matlab.ui.control.Label
        Wf_res                       matlab.ui.control.EditField
        W5Label_6                    matlab.ui.control.Label
        Wf_used                      matlab.ui.control.EditField
        W5Label_5                    matlab.ui.control.Label
        Wf                           matlab.ui.control.EditField
        W5Label_4                    matlab.ui.control.Label
        stallspeed                   matlab.ui.container.Tab
        stallplotenable              matlab.ui.control.CheckBox
        stallvs                      matlab.ui.control.EditField
        W5Label_22                   matlab.ui.control.Label
        stallcl                      matlab.ui.control.EditField
        W5Label_21                   matlab.ui.control.Label
        stallrho                     matlab.ui.control.EditField
        W5Label_20                   matlab.ui.control.Label
        stallheight                  matlab.ui.control.EditField
        W5Label_19                   matlab.ui.control.Label
        stallplot                    matlab.ui.control.CheckBox
        stallminus                   matlab.ui.control.Button
        stallplus                    matlab.ui.control.Button
        stallPlotButton              matlab.ui.control.Button
        stalltable                   matlab.ui.control.Table
        Tabla31Button1               matlab.ui.control.Button
        takeoffdistance              matlab.ui.container.Tab
        toplotenable                 matlab.ui.control.CheckBox
        toplot                       matlab.ui.control.CheckBox
        tominus                      matlab.ui.control.Button
        toplus                       matlab.ui.control.Button
        toPlotButton                 matlab.ui.control.Button
        Tabla31Button2               matlab.ui.control.Button
        toStofl                      matlab.ui.control.EditField
        W5Label_18                   matlab.ui.control.Label
        totable                      matlab.ui.control.Table
        toclmax                      matlab.ui.control.EditField
        W5Label_17                   matlab.ui.control.Label
        tosigma                      matlab.ui.control.EditField
        W5Label_16                   matlab.ui.control.Label
        totop25                      matlab.ui.control.EditField
        W5Label_15                   matlab.ui.control.Label
        toheight                     matlab.ui.control.EditField
        W5Label_14                   matlab.ui.control.Label
        landing                      matlab.ui.container.Tab
        lanplotenable                matlab.ui.control.CheckBox
        lanminus                     matlab.ui.control.Button
        lanplus                      matlab.ui.control.Button
        lanPlotButton                matlab.ui.control.Button
        lantable                     matlab.ui.control.Table
        lanrel                       matlab.ui.control.EditField
        W5Label_28                   matlab.ui.control.Label
        Tabla31Button3               matlab.ui.control.Button
        lansl                        matlab.ui.control.EditField
        W5Label_27                   matlab.ui.control.Label
        lancl                        matlab.ui.control.EditField
        W5Label_26                   matlab.ui.control.Label
        lanrho                       matlab.ui.control.EditField
        W5Label_25                   matlab.ui.control.Label
        lanheight                    matlab.ui.control.EditField
        W5Label_23                   matlab.ui.control.Label
        lanplot                      matlab.ui.control.CheckBox
        dragpolar                    matlab.ui.container.Tab
        dragv51                      matlab.ui.control.EditField
        C_DLabel_5                   matlab.ui.control.Label
        dragv52                      matlab.ui.control.EditField
        Label_6                      matlab.ui.control.Label
        draglabel52                  matlab.ui.control.Label
        dragv41                      matlab.ui.control.EditField
        C_DLabel_4                   matlab.ui.control.Label
        dragv42                      matlab.ui.control.EditField
        Label_5                      matlab.ui.control.Label
        draglabel42                  matlab.ui.control.Label
        dragv31                      matlab.ui.control.EditField
        C_DLabel_3                   matlab.ui.control.Label
        dragv32                      matlab.ui.control.EditField
        Label_4                      matlab.ui.control.Label
        draglabel32                  matlab.ui.control.Label
        draglabel22                  matlab.ui.control.Label
        dragv22                      matlab.ui.control.EditField
        Label_3                      matlab.ui.control.Label
        dragv21                      matlab.ui.control.EditField
        C_DLabel_2                   matlab.ui.control.Label
        draglabel12                  matlab.ui.control.Label
        dragv12                      matlab.ui.control.EditField
        Label_2                      matlab.ui.control.Label
        dragv11                      matlab.ui.control.EditField
        C_DLabel                     matlab.ui.control.Label
        draglabel5                   matlab.ui.control.Label
        draglabel4                   matlab.ui.control.Label
        draglabel3                   matlab.ui.control.Label
        draglabel2                   matlab.ui.control.Label
        draglabel1                   matlab.ui.control.Label
        dragtab                      matlab.ui.container.TabGroup
        dragtab1                     matlab.ui.container.Tab
        dragws                       matlab.ui.control.EditField
        W5Label_41                   matlab.ui.control.Label
        Tabla36Button1               matlab.ui.control.Button
        drage                        matlab.ui.control.EditField
        W5Label_40                   matlab.ui.control.Label
        dragrightarrowLabel          matlab.ui.control.Label
        draga                        matlab.ui.control.EditField
        W5Label_39                   matlab.ui.control.Label
        dragcd0                      matlab.ui.control.EditField
        W5Label_38                   matlab.ui.control.Label
        drags                        matlab.ui.control.EditField
        W5Label_37                   matlab.ui.control.Label
        dragf                        matlab.ui.control.EditField
        W5Label_36                   matlab.ui.control.Label
        dragcf                       matlab.ui.control.DropDown
        c_fDropDownLabel             matlab.ui.control.Label
        dragswet                     matlab.ui.control.EditField
        W5Label_35                   matlab.ui.control.Label
        dragtab2                     matlab.ui.container.Tab
        Tabla36Button2               matlab.ui.control.Button
        dragtable                    matlab.ui.control.Table
        climbtab                     matlab.ui.container.Tab
        climbappcl                   matlab.ui.control.EditField
        C_L_tomaxLabel_5             matlab.ui.control.Label
        climbapproachlabel           matlab.ui.control.Label
        climbwlwto                   matlab.ui.control.EditField
        C_L_tomaxLabel_4             matlab.ui.control.Label
        climbplotenable              matlab.ui.control.CheckBox
        climbPlotButton              matlab.ui.control.Button
        climblancl                   matlab.ui.control.EditField
        C_L_tomaxLabel_3             matlab.ui.control.Label
        climbercl                    matlab.ui.control.EditField
        C_L_tomaxLabel_2             matlab.ui.control.Label
        climbtocl                    matlab.ui.control.EditField
        C_L_tomaxLabel               matlab.ui.control.Label
        climblabel6                  matlab.ui.control.Label
        climblabel5                  matlab.ui.control.Label
        climblandinglabel            matlab.ui.control.Label
        climbenroutelabel            matlab.ui.control.Label
        climblabel4                  matlab.ui.control.Label
        climbvs6                     matlab.ui.control.EditField
        climbvs5                     matlab.ui.control.EditField
        climbvs4                     matlab.ui.control.EditField
        climbtable                   matlab.ui.control.Table
        climbvslabel                 matlab.ui.control.Label
        climbvs3                     matlab.ui.control.EditField
        climbvs2                     matlab.ui.control.EditField
        climbvs1                     matlab.ui.control.EditField
        climblabel3                  matlab.ui.control.Label
        climblabel2                  matlab.ui.control.Label
        climblabel1                  matlab.ui.control.Label
        climbminus                   matlab.ui.control.Button
        climbplus                    matlab.ui.control.Button
        climbtakeofflabel            matlab.ui.control.Label
        climbplot                    matlab.ui.control.CheckBox
        climbselect                  matlab.ui.control.DropDown
        ConfiguracinDropDownLabel    matlab.ui.control.Label
    end

    properties (Access = private)
        table21 = [
            [0.998, 0.998, 0.998, 0.995, 0.995];
            [0.995, 0.997, 0.998, 0.993, 0.993];
            [0.992, 0.996, 0.996, 0.992, 0.992];
            [0.996, 0.995, 0.996, 0.999, 0.998];
            [0.990, 0.995, 0.995, 0.990, 0.992];
            [0.990, 0.995, 0.995, 0.985, 0.995];
            [0.990, 0.990, 0.995, 0.990, 0.992];
            [0.990, 0.990, 0.990, 0.990, 0.995];
            [0.990, 0.990, 0.990, 0.990, 0.995];
            [0.990, 0.990, 0.995, 0.990, 0.992];
            [0.992, 0.990, 0.996, 0.990, 0.990];
            [0.990, 0.995, 0.995, 0.985, 0.992]
        ];
        table29 = transpose([
            % BOEING
            [184800, 100000];
            [112500, 61630];
            [124500, 69930];
            [775000, 380000];
            [630000, 325000];
            [220000, 130420];
            [300000, 179082];
            % McDONNELL-DOUGLAS
            [325000, 162700];
            [121000, 57190];
            [140000, 79757];
            [455000, 244903];
            [555000, 271062];
            [510000, 245500];
            [73000, 38683];
            [99650, 53762];
            [44000, 26850];
            [89500, 48500];
            % AIRBUS
            [363760, 195109];
            [291000, 168910];
            [357150, 153000];
            [198416, 95900]
        ]);
        tablecf = [
            [0.002, -2.6990, 1];
            [0.003, -2.5229, 1];
            [0.004, -2.3979, 1];
            [0.005, -2.3010, 1];
            [0.006, -2.2218, 1];
            [0.007, -2.1549, 1];
            [0.008, -2.0969, 1];
            [0.009, -2.0458, 1]
        ];
        prop = true;
        file = "";
        lasttoselection = 1;
        laststallselection = 1;
        lastlanselection = 1;
        rho0;
        climbinput = [
            [0, 0, 0, 0, 0, 0, 1];
        ];
        climboutput = [zeros(6, 4, 1) * nan, [1;1;1;1;1;1]];
    end
    methods (Access = private)
        function Tabla22Figure(app)
            f = figure(3);
            f.MenuBar = 'none';
            f.Name = 'Tabla 2.2';
            imshow('tabla22.png');
            set(gca,'units','pixels');
            x = get(gca,'position');
            set(gcf,'units','pixels');
            y = get(gcf,'position');
            set(gcf,'position',[y(1) y(2) x(3) x(4)]);
            set(gca,'units','normalized','position',[0 0 1 1]);
        end
        function Tabla31Figure(app)
            f = figure(4);
            f.MenuBar = 'none';
            f.Name = 'Tabla 3.1';
            imshow('tabla31.png');
            set(gca,'units','pixels');
            x = get(gca,'position');
            set(gcf,'units','pixels');
            y = get(gcf,'position');
            set(gcf,'position',[y(1) y(2) x(3) x(4)]);
            set(gca,'units','normalized','position',[0 0 1 1]); 
        end
        function Tabla36Figure(app)
            f = figure(6);
            f.MenuBar = 'none';
            f.Name = 'Tabla 3.6';
            imshow('tabla36.png');
            set(gca,'units','pixels');
            x = get(gca,'position');
            set(gcf,'units','pixels');
            y = get(gcf,'position');
            set(gcf,'position',[y(1) y(2) x(3) x(4)]);
            set(gca,'units','normalized','position',[0 0 1 1]); 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.init();
        end

        % Value changed function: tipo
        function tipoValueChanged(app, event)
            app.updateUIPlaneType();
        end

        % Value changed function: W1TO
        function W1TOValueChanged(app, event)
            app.A1_FuelFraction();
        end

        % Value changed function: W21
        function W21ValueChanged(app, event)
            app.A1_FuelFraction();
        end

        % Value changed function: W32
        function W32ValueChanged(app, event)
            app.A1_FuelFraction();
        end

        % Value changed function: W76
        function W76ValueChanged(app, event)
            app.A1_FuelFraction();
        end

        % Value changed function: W87
        function W87ValueChanged(app, event)
            app.A1_FuelFraction();
        end

        % Value changed function: W98
        function W98ValueChanged(app, event)
            app.A1_FuelFraction();
        end

        % Value changed function: E_cl
        function E_clValueChanged(app, event)
            app.A1_FuelFraction();
        end

        % Value changed function: V_cl
        function V_clValueChanged(app, event)
           app.A1_FuelFraction();
        end

        % Value changed function: R_cr
        function R_crValueChanged(app, event)
            app.A1_FuelFraction();
        end

        % Value changed function: c_cl
        function c_clValueChanged(app, event)
            app.A1_FuelFraction();
        end

        % Value changed function: n_p_cl
        function n_p_clValueChanged(app, event)
            app.A1_FuelFraction();
        end

        % Value changed function: LD_cl
        function LD_clValueChanged(app, event)
            app.A1_FuelFraction();
        end

        % Value changed function: V_cr
        function V_crValueChanged(app, event)
            app.A1_FuelFraction();
        end

        % Value changed function: c_cr
        function c_crValueChanged(app, event)
            app.A1_FuelFraction();
        end

        % Value changed function: n_p_cr
        function n_p_crValueChanged(app, event)
            app.A1_FuelFraction();
        end

        % Value changed function: LD_cr
        function LD_crValueChanged(app, event)
            app.A1_FuelFraction();
        end

        % Value changed function: V_lo
        function V_loValueChanged(app, event)
            app.A1_FuelFraction();
        end

        % Value changed function: c_lo
        function c_loValueChanged(app, event)
            app.A1_FuelFraction();
        end

        % Value changed function: n_p_lo
        function n_p_loValueChanged(app, event)
            app.A1_FuelFraction();
        end

        % Value changed function: LD_lo
        function LD_loValueChanged(app, event)
            app.A1_FuelFraction();
        end

        % Value changed function: E_lo
        function E_loValueChanged(app, event)
            app.A1_FuelFraction();
        end

        % Value changed function: W_to
        function W_toValueChanged(app, event)
            app.A1_FuelFraction();
        end

        % Button pushed function: save_new
        function save_newButtonPushed(app, event)
            app.saveFile(1);
        end

        % Button pushed function: load
        function loadButtonPushed(app, event)
            app.loadFile("");
        end

        % Button pushed function: save
        function saveButtonPushed(app, event)
            app.saveFile(0);
        end

        % Value changed function: Wf_res
        function Wf_resValueChanged(app, event)
            app.A2_TakeOffWeight();
        end

        % Value changed function: Wpl
        function WplValueChanged(app, event)
            app.A2_TakeOffWeight();
        end

        % Value changed function: Wcrew
        function WcrewValueChanged(app, event)
            app.A2_TakeOffWeight();
        end

        % Button pushed function: WPlotButton
        function WPlotButtonPushed(app, event)
            app.A_WeightPlot();
        end

        % Button pushed function: Tabla21Button
        function Tabla21ButtonPushed(app, event)
            f = figure(1);
            f.MenuBar = 'none';
            f.Name = 'Tabla 2.1';
            imshow('tabla21.png');
            set(gca,'units','pixels');
            x = get(gca,'position');
            set(gcf,'units','pixels');
            y = get(gcf,'position');
            set(gcf,'position',[y(1) y(2) x(3) x(4)]);
            set(gca,'units','normalized','position',[0 0 1 1]);
        end

        % Button pushed function: Tabla22Button1
        function Tabla22Button1Pushed(app, event)
            app.Tabla22Figure();
        end

        % Button pushed function: Tabla22Button2
        function Tabla22Button2Pushed(app, event)
            app.Tabla22Figure();
        end

        % Button pushed function: Tabla22Button3
        function Tabla22Button3Pushed(app, event)
            app.Tabla22Figure();
        end

        % Button pushed function: Tabla31Button1
        function Tabla31Button1Pushed(app, event)
            app.Tabla31Figure();
        end

        % Button pushed function: Tabla31Button2
        function Tabla31Button2Pushed(app, event)
            app.Tabla31Figure();
        end

        % Button pushed function: Tabla31Button3
        function Tabla31Button3Pushed(app, event)
            app.Tabla31Figure();
        end

        % Button pushed function: Tabla36Button1
        function Tabla36Button1Pushed(app, event)
            app.Tabla36Figure();
        end

        % Button pushed function: Tabla36Button2
        function Tabla36Button2Pushed(app, event)
            app.Tabla36Figure();
        end

        % Selection changed function: stalltable
        function stalltableSelectionChanged(app, event)
            if app.stalltable.Selection >= 1
            else
                app.stalltable.Selection = app.laststallselection;
            end
            app.laststallselection = app.stalltable.Selection;
            i = app.laststallselection;
            app.stallvs.Value = num2str(app.stalltable.Data(i,1));
            app.stallheight.Value = num2str(app.stalltable.Data(i,2));
            app.stallcl.Value = num2str(app.stalltable.Data(i,3));
            app.stallplot.Value = app.stalltable.Data(i, 5);
            app.B1_StallPartialResults();
        end

        % Selection changed function: totable
        function totableSelectionChanged(app, event)
            if app.totable.Selection >= 1
            else
                app.totable.Selection = app.lasttoselection;
            end
            app.lasttoselection = app.totable.Selection;
            i = app.lasttoselection;
            app.toStofl.Value = num2str(app.totable.Data(i,1));
            app.toheight.Value = num2str(app.totable.Data(i,2));
            app.toclmax.Value = num2str(app.totable.Data(i,3));
            app.toplot.Value = app.totable.Data(i, 4);
            app.B2_TakeOffPartialResults();
        end

        % Selection changed function: lantable
        function lantableSelectionChanged(app, event)
            if app.lantable.Selection >= 1
            else
                app.lantable.Selection = app.lastlanselection;
            end
            app.lastlanselection = app.lantable.Selection;
            i = app.lastlanselection;
            app.lansl.Value = num2str(app.lantable.Data(i,1));
            app.lanheight.Value = num2str(app.lantable.Data(i,2));
            app.lancl.Value = num2str(app.lantable.Data(i,3));
            app.lanrel.Value = num2str(app.lantable.Data(i,4));
            app.lanplot.Value = app.lantable.Data(i, 5);
            app.B3_LandingPartialResults();
        end

        % Button pushed function: stallplus
        function stallplusButtonPushed(app, event)
            i = app.laststallselection;
            app.stalltable.Data = [app.stalltable.Data; app.stalltable.Data(i,:)];
            app.stalltable.Selection = height(app.stalltable.Data);
            app.stalltableSelectionChanged(1);
            if ishandle(5)
                app.B_TWWSPlot()
            end
        end

        % Button pushed function: toplus
        function toplusButtonPushed(app, event)
            i = app.lasttoselection;
            app.totable.Data = [app.totable.Data; app.totable.Data(i,:)];
            app.totable.Selection = height(app.totable.Data);
            app.totableSelectionChanged(1);
            if ishandle(5)
                app.B_TWWSPlot()
            end
        end

        % Button pushed function: lanplus
        function lanplusButtonPushed(app, event)
            i = app.lastlanselection;
            app.lantable.Data = [app.lantable.Data; app.lantable.Data(i,:)];
            app.lantable.Selection = height(app.lantable.Data);
            app.lantableSelectionChanged(1);
            if ishandle(5)
                app.B_TWWSPlot()
            end
        end

        % Button pushed function: stallminus
        function stallminusButtonPushed(app, event)
            if height(app.stalltable.Data) > 1
                i = app.laststallselection;
                app.stalltable.Data(i,:) = [];
                if i > 1
                    app.stalltable.Selection = i-1;
                end
                app.stalltableSelectionChanged(1);
            end
            if ishandle(5)
                app.B_TWWSPlot()
            end
        end

        % Button pushed function: tominus
        function tominusButtonPushed(app, event)
            if height(app.totable.Data) > 1
                i = app.lasttoselection;
                app.totable.Data(i,:) = [];
                if i > 1
                    app.totable.Selection = i-1;
                end
                app.totableSelectionChanged(1);
            end
            if ishandle(5)
                app.B_TWWSPlot()
            end
        end

        % Button pushed function: lanminus
        function lanminusButtonPushed(app, event)
            if height(app.lantable.Data) > 1
                i = app.lastlanselection;
                app.lantable.Data(i,:) = [];
                if i > 1
                    app.lantable.Selection = i-1;
                end
                app.lantableSelectionChanged(1);
            end
            if ishandle(5)
                app.B_TWWSPlot()
            end
        end

        % Value changed function: stallvs
        function stallvsValueChanged(app, event)
            app.B1_StallTable();
        end

        % Value changed function: stallheight
        function stallheightValueChanged(app, event)
            app.B1_StallTable();
        end

        % Value changed function: stallcl
        function stallclValueChanged(app, event)
            app.B1_StallTable();
        end

        % Value changed function: toStofl
        function toStoflValueChanged(app, event)
            app.B2_TakeoffTable();
        end

        % Value changed function: toheight
        function toheightValueChanged(app, event)
            app.B2_TakeoffTable();
        end

        % Value changed function: toclmax
        function toclmaxValueChanged(app, event)
            app.B2_TakeoffTable();
        end

        % Value changed function: lansl
        function lanslValueChanged(app, event)
            app.B3_LandingTable();
        end

        % Value changed function: lanrel
        function lanrelValueChanged(app, event)
            app.B3_LandingTable();
        end

        % Value changed function: lanheight
        function lanheightValueChanged(app, event)
            app.B3_LandingTable();
        end

        % Value changed function: lancl
        function lanclValueChanged(app, event)
            app.B3_LandingTable();
        end

        % Button pushed function: stallPlotButton
        function stallPlotButtonPushed(app, event)
            app.B_TWWSPlot();
        end

        % Button pushed function: toPlotButton
        function toPlotButtonPushed(app, event)
            app.B_TWWSPlot();
        end

        % Button pushed function: lanPlotButton
        function lanPlotButtonPushed(app, event)
            app.B_TWWSPlot();
        end

        % Button pushed function: climbPlotButton
        function climbPlotButtonPushed(app, event)
            app.B_TWWSPlot();
        end

        % Value changed function: stallplot
        function stallplotValueChanged(app, event)
            app.B1_StallTable();
        end

        % Value changed function: toplot
        function toplotValueChanged(app, event)
            app.B2_TakeOffTable();
        end

        % Value changed function: lanplot
        function lanplotValueChanged(app, event)
            app.B3_LandingTable();
        end

        % Value changed function: stallplotenable
        function stallplotenableValueChanged(app, event)
            if ishandle(5)
                app.B_TWWSPlot();
            end
        end

        % Value changed function: toplotenable
        function toplotenableValueChanged(app, event)
            if ishandle(5)
                app.B_TWWSPlot();
            end
        end

        % Value changed function: lanplotenable
        function lanplotenableValueChanged(app, event)
            if ishandle(5)
                app.B_TWWSPlot();
            end
        end

        % Value changed function: climbplotenable
        function climbplotenableValueChanged(app, event)
            if ishandle(5)
                app.B_TWWSPlot();
            end
        end

        % Value changed function: dragcf
        function dragcfValueChanged(app, event)
            app.B41_DragPolar();
        end

        % Value changed function: drags
        function dragsValueChanged(app, event)
            app.B41_DragPolar();
        end

        % Value changed function: draga
        function dragaValueChanged(app, event)
            app.B41_DragPolar();
        end

        % Value changed function: drage
        function drageValueChanged(app, event)
            app.B41_DragPolar();
        end

        % Value changed function: dragswet
        function dragswetValueChanged(app, event)
            app.B41_DragPolar();
        end

        % Cell edit callback: dragtable
        function dragtableCellEdit(app, event)
            app.dragtable.Data(3,2) = nan;
            app.B41_DragPolar();
        end

        % Value changed function: climbtocl
        function climbtoclValueChanged(app, event)
            i = str2double(app.climbselect.Value);
            app.climbinput(i,1) = str2double(app.climbtocl.Value);
            app.B42_Climb();
        end

        % Value changed function: climbercl
        function climberclValueChanged(app, event)
            i = str2double(app.climbselect.Value);
            app.climbinput(i,2) = str2double(app.climbercl.Value);
            app.B42_Climb();
        end

        % Value changed function: climblancl
        function climblanclValueChanged(app, event)
            i = str2double(app.climbselect.Value);
            app.climbinput(i,3) = str2double(app.climblancl.Value);
            app.B42_Climb();
        end

        % Value changed function: climbvs2
        function climbvs2ValueChanged(app, event)
            i = str2double(app.climbselect.Value);
            app.climbinput(i,6) = str2double(app.climbvs2.Value);
            app.B42_Climb();
        end

        % Value changed function: climbwlwto
        function climbwlwtoValueChanged(app, event)
            i = str2double(app.climbselect.Value);
            app.climbinput(i,4) = str2double(app.climbwlwto.Value);
            app.B42_Climb();
        end

        % Value changed function: climbappcl
        function climbappclValueChanged(app, event)
            i = str2double(app.climbselect.Value);
            app.climbinput(i,5) = str2double(app.climbappcl.Value);
            app.B42_Climb();
        end

        % Button pushed function: climbplus
        function climbplusButtonPushed(app, event)
            len = length(app.climbselect.Items);
            app.climbselect.Items = [app.climbselect.Items, {num2str(len+1)}];
            i = str2double(app.climbselect.Value);
            app.climboutput(:,:,len+1) = app.climboutput(:,:,i);
            app.climbinput(len+1,:) = app.climbinput(i,:);
            app.climbselect.Value = num2str(len+1);
        end

        % Button pushed function: climbminus
        function climbminusButtonPushed(app, event)
            len = size(app.climboutput, 3);
            if len > 1
                i = str2double(app.climbselect.Value);
                app.climbinput(i,:) = [];
                app.climboutput(:,:,i) = [];
                app.climbselect.Items = string(1:len-1);
                if i > 1
                    app.climbselect.Value = num2str(i - 1);
                end
                app.B42_Climb();
            end
        end

        % Value changed function: climbselect
        function climbselectValueChanged(app, event)
            app.B42_Climb();
        end

        % Value changed function: climbplot
        function climbplotValueChanged(app, event)
            i = str2double(app.climbselect.Value);
            app.climbinput(i,7) = app.climbplot.Value;
            app.B42_Climb();
        end

        % Cell edit callback: climbtable
        function climbtableCellEdit(app, event)
            i = str2double(app.climbselect.Value);
            app.climboutput(:,5,i) = app.climbtable.Data(:,5);
            if ishandle(5)
                app.B_TWWSPlot();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.resumen = uitab(app.TabGroup);
            app.resumen.Title = 'Resumen';
            app.TipodeaeronaveDropDownLabel = uilabel(app.resumen);
            app.TipodeaeronaveDropDownLabel.Text = 'Tipo de aeronave';
            app.tipo = uidropdown(app.resumen);
            app.tipo.Items = {'Homebuilt Propeller Driven Airplanes', 'Single Engine Propeller Airplanes', 'Twin Engine Propeller Airplanes', 'Agricultural Airplanes', 'Business Jets', 'Regional Turbopropeller Driven Airplanes', 'Transport Jets', 'Military Trainers', 'Fighters', 'Military Patrol, Bomb and Transport Airplanes', 'Flying Boats, Amphibious and Float Airplanes', 'Supersonic Cruise Airplanes'};
            app.tipo.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.tipo.ValueChangedFcn = createCallbackFcn(app, @tipoValueChanged, true);
            app.tipo.Enable = 'off';
            app.tipo.Value = '7';
            app.Label = uilabel(app.resumen);
            app.fc = uitab(app.TabGroup);
            app.fc.Title = 'Fracción de combustible';
            app.W_1Label = uilabel(app.fc);
            app.W_1Label.Interpreter = 'latex';
            app.W_1Label.Text = '$W_{1/TO}$';
            app.W1TO = uieditfield(app.fc, 'text');
            app.W1TO.ValueChangedFcn = createCallbackFcn(app, @W1TOValueChanged, true);
            app.W2Label = uilabel(app.fc);
            app.W2Label.Interpreter = 'latex';
            app.W2Label.Text = '$W_{2/1}$';
            app.W21 = uieditfield(app.fc, 'text');
            app.W21.ValueChangedFcn = createCallbackFcn(app, @W21ValueChanged, true);
            app.W3Label = uilabel(app.fc);
            app.W3Label.Interpreter = 'latex';
            app.W3Label.Text = '$W_{3/2}$';
            app.W32 = uieditfield(app.fc, 'text');
            app.W32.ValueChangedFcn = createCallbackFcn(app, @W32ValueChanged, true);
            app.W4Label = uilabel(app.fc);
            app.W4Label.Interpreter = 'latex';
            app.W4Label.Text = '$W_{4/3}$';
            app.W43 = uieditfield(app.fc, 'text');
            app.W43.Editable = 'off';
            app.W5Label = uilabel(app.fc);
            app.W5Label.Interpreter = 'latex';
            app.W5Label.Text = '$W_{5/4}$';
            app.W54 = uieditfield(app.fc, 'text');
            app.W54.Editable = 'off';
            app.W6Label = uilabel(app.fc);
            app.W6Label.Interpreter = 'latex';
            app.W6Label.Text = '$W_{6/5}$';
            app.W65 = uieditfield(app.fc, 'text');
            app.W65.Editable = 'off';
            app.W7Label = uilabel(app.fc);
            app.W7Label.Interpreter = 'latex';
            app.W7Label.Text = '$W_{7/6}$';
            app.W76 = uieditfield(app.fc, 'text');
            app.W76.ValueChangedFcn = createCallbackFcn(app, @W76ValueChanged, true);
            app.W8Label = uilabel(app.fc);
            app.W8Label.Interpreter = 'latex';
            app.W8Label.Text = '$W_{8/7}$';
            app.W87 = uieditfield(app.fc, 'text');
            app.W87.ValueChangedFcn = createCallbackFcn(app, @W87ValueChanged, true);
            app.M_ffEditFieldLabel = uilabel(app.fc);
            app.M_ffEditFieldLabel.Interpreter = 'latex';
            app.M_ffEditFieldLabel.Text = '$M_{ff}$';
            app.m_ff = uieditfield(app.fc, 'text');
            app.m_ff.Editable = 'off';
            app.TabGroup2 = uitabgroup(app.fc);
            app.AscensoTab = uitab(app.TabGroup2);
            app.AscensoTab.Title = 'Ascenso';
            app.W4Label_2 = uilabel(app.AscensoTab);
            app.W4Label_2.Interpreter = 'latex';
            app.W4Label_2.Text = '$E$';
            app.E_cl = uieditfield(app.AscensoTab, 'text');
            app.E_cl.ValueChangedFcn = createCallbackFcn(app, @E_clValueChanged, true);
            app.E_cl.Tooltip = {'hr'};
            app.W4Label_3 = uilabel(app.AscensoTab);
            app.W4Label_3.Interpreter = 'latex';
            app.W4Label_3.Text = '$\frac{L}{D}$';
            app.LD_cl = uieditfield(app.AscensoTab, 'text');
            app.LD_cl.ValueChangedFcn = createCallbackFcn(app, @LD_clValueChanged, true);
            app.V_cl = uieditfield(app.AscensoTab, 'text');
            app.V_cl.ValueChangedFcn = createCallbackFcn(app, @V_clValueChanged, true);
            app.V_cl.Tooltip = {''};
            app.W4Label_4 = uilabel(app.AscensoTab);
            app.W4Label_4.Interpreter = 'latex';
            app.W4Label_4.Text = '$V$';
            app.W4Label_5 = uilabel(app.AscensoTab);
            app.W4Label_5.Interpreter = 'latex';
            app.W4Label_5.Text = '$c$';
            app.c_cl = uieditfield(app.AscensoTab, 'text');
            app.c_cl.ValueChangedFcn = createCallbackFcn(app, @c_clValueChanged, true);
            app.W4Label_7 = uilabel(app.AscensoTab);
            app.W4Label_7.Interpreter = 'latex';
            app.W4Label_7.Text = '$\eta_p$';
            app.n_p_cl = uieditfield(app.AscensoTab, 'text');
            app.n_p_cl.ValueChangedFcn = createCallbackFcn(app, @n_p_clValueChanged, true);
            app.Tabla22Button1 = uibutton(app.AscensoTab, 'push');
            app.Tabla22Button1.ButtonPushedFcn = createCallbackFcn(app, @Tabla22Button1Pushed, true);
            app.Tabla22Button1.Text = 'Tabla 2.2';
            app.CruceroTab = uitab(app.TabGroup2);
            app.CruceroTab.Title = 'Crucero';
            app.W4Label_8 = uilabel(app.CruceroTab);
            app.W4Label_8.Interpreter = 'latex';
            app.W4Label_8.Text = '$R$';
            app.R_cr = uieditfield(app.CruceroTab, 'text');
            app.R_cr.ValueChangedFcn = createCallbackFcn(app, @R_crValueChanged, true);
            app.R_cr.Tooltip = {'nm'};
            app.W4Label_9 = uilabel(app.CruceroTab);
            app.W4Label_9.Interpreter = 'latex';
            app.W4Label_9.Text = '$c$';
            app.c_cr = uieditfield(app.CruceroTab, 'text');
            app.c_cr.ValueChangedFcn = createCallbackFcn(app, @c_crValueChanged, true);
            app.W4Label_10 = uilabel(app.CruceroTab);
            app.W4Label_10.Interpreter = 'latex';
            app.W4Label_10.Text = '$\eta_p$';
            app.n_p_cr = uieditfield(app.CruceroTab, 'text');
            app.n_p_cr.ValueChangedFcn = createCallbackFcn(app, @n_p_crValueChanged, true);
            app.W4Label_11 = uilabel(app.CruceroTab);
            app.W4Label_11.Interpreter = 'latex';
            app.W4Label_11.Text = '$\frac{L}{D}$';
            app.LD_cr = uieditfield(app.CruceroTab, 'text');
            app.LD_cr.ValueChangedFcn = createCallbackFcn(app, @LD_crValueChanged, true);
            app.W4Label_12 = uilabel(app.CruceroTab);
            app.W4Label_12.Interpreter = 'latex';
            app.W4Label_12.Text = '$V$';
            app.V_cr = uieditfield(app.CruceroTab, 'text');
            app.V_cr.ValueChangedFcn = createCallbackFcn(app, @V_crValueChanged, true);
            app.V_cr.Tooltip = {''};
            app.Tabla22Button2 = uibutton(app.CruceroTab, 'push');
            app.Tabla22Button2.ButtonPushedFcn = createCallbackFcn(app, @Tabla22Button2Pushed, true);
            app.Tabla22Button2.Text = 'Tabla 2.2';
            app.LoiterTab = uitab(app.TabGroup2);
            app.LoiterTab.Title = 'Loiter';
            app.W4Label_13 = uilabel(app.LoiterTab);
            app.W4Label_13.Interpreter = 'latex';
            app.W4Label_13.Text = '$\frac{L}{D}$';
            app.LD_lo = uieditfield(app.LoiterTab, 'text');
            app.LD_lo.ValueChangedFcn = createCallbackFcn(app, @LD_loValueChanged, true);
            app.W4Label_14 = uilabel(app.LoiterTab);
            app.W4Label_14.Interpreter = 'latex';
            app.W4Label_14.Text = '$c$';
            app.c_lo = uieditfield(app.LoiterTab, 'text');
            app.c_lo.ValueChangedFcn = createCallbackFcn(app, @c_loValueChanged, true);
            app.W4Label_15 = uilabel(app.LoiterTab);
            app.W4Label_15.Interpreter = 'latex';
            app.W4Label_15.Text = '$\eta_p$';
            app.n_p_lo = uieditfield(app.LoiterTab, 'text');
            app.n_p_lo.ValueChangedFcn = createCallbackFcn(app, @n_p_loValueChanged, true);
            app.W4Label_16 = uilabel(app.LoiterTab);
            app.W4Label_16.Interpreter = 'latex';
            app.W4Label_16.Text = '$V$';
            app.V_lo = uieditfield(app.LoiterTab, 'text');
            app.V_lo.ValueChangedFcn = createCallbackFcn(app, @V_loValueChanged, true);
            app.V_lo.Tooltip = {'S'};
            app.W4Label_17 = uilabel(app.LoiterTab);
            app.W4Label_17.Interpreter = 'latex';
            app.W4Label_17.Text = '$E$';
            app.E_lo = uieditfield(app.LoiterTab, 'text');
            app.E_lo.ValueChangedFcn = createCallbackFcn(app, @E_loValueChanged, true);
            app.E_lo.Tooltip = {'hr'};
            app.Tabla22Button3 = uibutton(app.LoiterTab, 'push');
            app.Tabla22Button3.ButtonPushedFcn = createCallbackFcn(app, @Tabla22Button3Pushed, true);
            app.Tabla22Button3.Text = 'Tabla 2.2';
            app.W8Label_2 = uilabel(app.fc);
            app.W8Label_2.Interpreter = 'latex';
            app.W8Label_2.Text = '$W_{9/8}$';
            app.W98 = uieditfield(app.fc, 'text');
            app.W98.ValueChangedFcn = createCallbackFcn(app, @W98ValueChanged, true);
            app.Tabla21Button = uibutton(app.fc, 'push');
            app.Tabla21Button.ButtonPushedFcn = createCallbackFcn(app, @Tabla21ButtonPushed, true);
            app.Tabla21Button.Text = 'Tabla 2.1';
            app.emptyweight = uitab(app.TabGroup);
            app.emptyweight.Title = 'Peso en vacío';
            app.W5Label_4 = uilabel(app.emptyweight);
            app.W5Label_4.Interpreter = 'latex';
            app.W5Label_4.Text = '$W_F$';
            app.Wf = uieditfield(app.emptyweight, 'text');
            app.Wf.Editable = 'off';
            app.W5Label_5 = uilabel(app.emptyweight);
            app.W5Label_5.Interpreter = 'latex';
            app.W5Label_5.Text = '$=W_{f,used}$';
            app.Wf_used = uieditfield(app.emptyweight, 'text');
            app.Wf_used.Editable = 'off';
            app.W5Label_6 = uilabel(app.emptyweight);
            app.W5Label_6.Interpreter = 'latex';
            app.W5Label_6.Text = '$+W_{f,res}$';
            app.Wf_res = uieditfield(app.emptyweight, 'text');
            app.Wf_res.ValueChangedFcn = createCallbackFcn(app, @Wf_resValueChanged, true);
            app.Wf_res.Editable = 'off';
            app.W5Label_7 = uilabel(app.emptyweight);
            app.W5Label_7.Interpreter = 'latex';
            app.W5Label_7.Text = '$W_{tfo}$';
            app.Wtfo = uieditfield(app.emptyweight, 'text');
            app.Wtfo.Editable = 'off';
            app.W5Label_8 = uilabel(app.emptyweight);
            app.W5Label_8.Interpreter = 'latex';
            app.W5Label_8.Text = '$W_{PL}$';
            app.Wpl = uieditfield(app.emptyweight, 'text');
            app.Wpl.ValueChangedFcn = createCallbackFcn(app, @WplValueChanged, true);
            app.W5Label_9 = uilabel(app.emptyweight);
            app.W5Label_9.Interpreter = 'latex';
            app.W5Label_9.Text = '$W_{crew}$';
            app.Wcrew = uieditfield(app.emptyweight, 'text');
            app.Wcrew.ValueChangedFcn = createCallbackFcn(app, @WcrewValueChanged, true);
            app.W5Label_10 = uilabel(app.emptyweight);
            app.W5Label_10.Interpreter = 'latex';
            app.W5Label_10.Text = '$W_E$';
            app.WE = uieditfield(app.emptyweight, 'text');
            app.WE.Editable = 'off';
            app.we_eq = uilabel(app.emptyweight);
            app.we_eq.Interpreter = 'latex';
            app.we_eq.Text = '$=W_{to}-W_F-W_{PL}-W_{tfo}-W_{crew}$';
            app.W5Label_11 = uilabel(app.emptyweight);
            app.W5Label_11.Interpreter = 'latex';
            app.W5Label_11.Text = '$W_{to}$';
            app.W_to = uieditfield(app.emptyweight, 'text');
            app.W_to.ValueChangedFcn = createCallbackFcn(app, @W_toValueChanged, true);
            app.W_to.Tooltip = {''};
            app.WPlotButton = uibutton(app.emptyweight, 'push');
            app.WPlotButton.ButtonPushedFcn = createCallbackFcn(app, @WPlotButtonPushed, true);
            app.WPlotButton.Text = 'Plot';
            app.W5Label_12 = uilabel(app.emptyweight);
            app.W5Label_12.Interpreter = 'latex';
            app.W5Label_12.Text = '$Err$';
            app.WError = uieditfield(app.emptyweight, 'text');
            app.WError.Editable = 'off';
            app.W5Label_13 = uilabel(app.emptyweight);
            app.W5Label_13.Interpreter = 'latex';
            app.W5Label_13.Text = '$\rightarrow W_{E, target}$';
            app.WEtarget = uieditfield(app.emptyweight, 'text');
            app.WEtarget.Editable = 'off';
            app.WEtarget.Tooltip = {''};
            app.eqerror = uilabel(app.emptyweight);
            app.eqerror.Interpreter = 'latex';
            app.eqerror.Text = '$=\frac{|W_E-W_{E,target}|}{W_{E,target}}\cdot 100$';
            app.stallspeed = uitab(app.TabGroup);
            app.stallspeed.Title = 'Stall Sizing';
            app.Tabla31Button1 = uibutton(app.stallspeed, 'push');
            app.Tabla31Button1.ButtonPushedFcn = createCallbackFcn(app, @Tabla31Button1Pushed, true);
            app.Tabla31Button1.Text = 'Tabla 3.1';
            app.stalltable = uitable(app.stallspeed);
            app.stalltable.ColumnName = {'Vs'; 'h'; 'CL'; 'W/S'; 'plot'};
            app.stalltable.SelectionType = 'row';
            app.stalltable.SelectionChangedFcn = createCallbackFcn(app, @stalltableSelectionChanged, true);
            app.stalltable.Multiselect = 'off';
            app.stallPlotButton = uibutton(app.stallspeed, 'push');
            app.stallPlotButton.ButtonPushedFcn = createCallbackFcn(app, @stallPlotButtonPushed, true);
            app.stallPlotButton.Text = 'Plot T/W vs W/S';
            app.stallplus = uibutton(app.stallspeed, 'push');
            app.stallplus.ButtonPushedFcn = createCallbackFcn(app, @stallplusButtonPushed, true);
            app.stallplus.Text = '+';
            app.stallminus = uibutton(app.stallspeed, 'push');
            app.stallminus.ButtonPushedFcn = createCallbackFcn(app, @stallminusButtonPushed, true);
            app.stallminus.Text = '-';
            app.stallplot = uicheckbox(app.stallspeed);
            app.stallplot.ValueChangedFcn = createCallbackFcn(app, @stallplotValueChanged, true);
            app.stallplot.Text = 'Plot';
            app.stallplot.Value = true;
            app.W5Label_19 = uilabel(app.stallspeed);
            app.W5Label_19.Interpreter = 'latex';
            app.W5Label_19.Text = '$h$';
            app.stallheight = uieditfield(app.stallspeed, 'text');
            app.stallheight.ValueChangedFcn = createCallbackFcn(app, @stallheightValueChanged, true);
            app.stallheight.Tooltip = {'ft'};
            app.stallheight.Value = '0';
            app.W5Label_20 = uilabel(app.stallspeed);
            app.W5Label_20.Interpreter = 'latex';
            app.W5Label_20.Text = '$\rightarrow \rho$';
            app.stallrho = uieditfield(app.stallspeed, 'text');
            app.stallrho.Editable = 'off';
            app.stallrho.Tooltip = {'slug/ft3'};
            app.W5Label_21 = uilabel(app.stallspeed);
            app.W5Label_21.Interpreter = 'latex';
            app.W5Label_21.Text = {'$C_L'; '$'};
            app.stallcl = uieditfield(app.stallspeed, 'text');
            app.stallcl.ValueChangedFcn = createCallbackFcn(app, @stallclValueChanged, true);
            app.stallcl.Tooltip = {''};
            app.stallcl.Value = '1.1';
            app.W5Label_22 = uilabel(app.stallspeed);
            app.W5Label_22.Interpreter = 'latex';
            app.W5Label_22.Text = '$V_s$';
            app.stallvs = uieditfield(app.stallspeed, 'text');
            app.stallvs.ValueChangedFcn = createCallbackFcn(app, @stallvsValueChanged, true);
            app.stallvs.Tooltip = {'ktas'};
            app.stallvs.Value = '0';
            app.stallplotenable = uicheckbox(app.stallspeed);
            app.stallplotenable.ValueChangedFcn = createCallbackFcn(app, @stallplotenableValueChanged, true);
            app.stallplotenable.Text = 'Plot Stall Sizing';
            app.stallplotenable.Value = true;
            app.takeoffdistance = uitab(app.TabGroup);
            app.takeoffdistance.Title = 'Take-Off Sizing';
            app.W5Label_14 = uilabel(app.takeoffdistance);
            app.W5Label_14.Interpreter = 'latex';
            app.W5Label_14.Text = '$h$';
            app.toheight = uieditfield(app.takeoffdistance, 'text');
            app.toheight.ValueChangedFcn = createCallbackFcn(app, @toheightValueChanged, true);
            app.toheight.Tooltip = {'ft'};
            app.toheight.Value = '0';
            app.W5Label_15 = uilabel(app.takeoffdistance);
            app.W5Label_15.Interpreter = 'latex';
            app.W5Label_15.Text = '$\rightarrow TOP_{25}$';
            app.totop25 = uieditfield(app.takeoffdistance, 'text');
            app.totop25.Editable = 'off';
            app.totop25.Tooltip = {''};
            app.W5Label_16 = uilabel(app.takeoffdistance);
            app.W5Label_16.Interpreter = 'latex';
            app.W5Label_16.Text = '$\rightarrow \sigma$';
            app.tosigma = uieditfield(app.takeoffdistance, 'text');
            app.tosigma.Editable = 'off';
            app.tosigma.Tooltip = {''};
            app.W5Label_17 = uilabel(app.takeoffdistance);
            app.W5Label_17.Interpreter = 'latex';
            app.W5Label_17.Text = {'$C_{L_{max,TO'; '}}$'};
            app.toclmax = uieditfield(app.takeoffdistance, 'text');
            app.toclmax.ValueChangedFcn = createCallbackFcn(app, @toclmaxValueChanged, true);
            app.toclmax.Tooltip = {''};
            app.toclmax.Value = '1.1';
            app.totable = uitable(app.takeoffdistance);
            app.totable.ColumnName = {'Stofl'; 'h'; 'CLmax'; 'plot'};
            app.totable.SelectionType = 'row';
            app.totable.SelectionChangedFcn = createCallbackFcn(app, @totableSelectionChanged, true);
            app.totable.Multiselect = 'off';
            app.W5Label_18 = uilabel(app.takeoffdistance);
            app.W5Label_18.Interpreter = 'latex';
            app.W5Label_18.Text = '$S_{TOFL}$';
            app.toStofl = uieditfield(app.takeoffdistance, 'text');
            app.toStofl.ValueChangedFcn = createCallbackFcn(app, @toStoflValueChanged, true);
            app.toStofl.Tooltip = {'ft'};
            app.toStofl.Value = '0';
            app.Tabla31Button2 = uibutton(app.takeoffdistance, 'push');
            app.Tabla31Button2.ButtonPushedFcn = createCallbackFcn(app, @Tabla31Button2Pushed, true);
            app.Tabla31Button2.Text = 'Tabla 3.1';
            app.toPlotButton = uibutton(app.takeoffdistance, 'push');
            app.toPlotButton.ButtonPushedFcn = createCallbackFcn(app, @toPlotButtonPushed, true);
            app.toPlotButton.Text = 'Plot T/W vs W/S';
            app.toplus = uibutton(app.takeoffdistance, 'push');
            app.toplus.ButtonPushedFcn = createCallbackFcn(app, @toplusButtonPushed, true);
            app.toplus.Text = '+';
            app.tominus = uibutton(app.takeoffdistance, 'push');
            app.tominus.ButtonPushedFcn = createCallbackFcn(app, @tominusButtonPushed, true);
            app.tominus.Text = '-';
            app.toplot = uicheckbox(app.takeoffdistance);
            app.toplot.ValueChangedFcn = createCallbackFcn(app, @toplotValueChanged, true);
            app.toplot.Text = 'Plot';
            app.toplot.Value = true;
            app.toplotenable = uicheckbox(app.takeoffdistance);
            app.toplotenable.ValueChangedFcn = createCallbackFcn(app, @toplotenableValueChanged, true);
            app.toplotenable.Text = 'Plot Take-Off Sizing';
            app.toplotenable.Value = true;
            app.landing = uitab(app.TabGroup);
            app.landing.Title = 'Landing Sizing';
            app.lanplot = uicheckbox(app.landing);
            app.lanplot.ValueChangedFcn = createCallbackFcn(app, @lanplotValueChanged, true);
            app.lanplot.Text = 'Plot';
            app.lanplot.Value = true;
            app.W5Label_23 = uilabel(app.landing);
            app.W5Label_23.Interpreter = 'latex';
            app.W5Label_23.Text = '$h$';
            app.lanheight = uieditfield(app.landing, 'text');
            app.lanheight.ValueChangedFcn = createCallbackFcn(app, @lanheightValueChanged, true);
            app.lanheight.Tooltip = {'ft'};
            app.lanheight.Value = '0';
            app.W5Label_25 = uilabel(app.landing);
            app.W5Label_25.Interpreter = 'latex';
            app.W5Label_25.Text = '$\rightarrow \rho$';
            app.lanrho = uieditfield(app.landing, 'text');
            app.lanrho.Editable = 'off';
            app.lanrho.Tooltip = {'slug/ft3'};
            app.W5Label_26 = uilabel(app.landing);
            app.W5Label_26.Interpreter = 'latex';
            app.W5Label_26.Text = '$C_{L_{max,L}}$';
            app.lancl = uieditfield(app.landing, 'text');
            app.lancl.ValueChangedFcn = createCallbackFcn(app, @lanclValueChanged, true);
            app.lancl.Tooltip = {''};
            app.lancl.Value = '1.1';
            app.W5Label_27 = uilabel(app.landing);
            app.W5Label_27.Interpreter = 'latex';
            app.W5Label_27.Text = '$s_{L}$';
            app.lansl = uieditfield(app.landing, 'text');
            app.lansl.ValueChangedFcn = createCallbackFcn(app, @lanslValueChanged, true);
            app.lansl.Tooltip = {'ft'};
            app.lansl.Value = '0';
            app.Tabla31Button3 = uibutton(app.landing, 'push');
            app.Tabla31Button3.ButtonPushedFcn = createCallbackFcn(app, @Tabla31Button3Pushed, true);
            app.Tabla31Button3.Text = 'Tabla 3.1';
            app.W5Label_28 = uilabel(app.landing);
            app.W5Label_28.Interpreter = 'latex';
            app.W5Label_28.Text = '$W_{L}=W_{TO}\cdot$';
            app.lanrel = uieditfield(app.landing, 'text');
            app.lanrel.ValueChangedFcn = createCallbackFcn(app, @lanrelValueChanged, true);
            app.lanrel.Tooltip = {''};
            app.lanrel.Value = '0';
            app.lantable = uitable(app.landing);
            app.lantable.ColumnName = {'SL'; 'h'; 'CL'; 'Wl=Wto'; 'plot'};
            app.lantable.SelectionType = 'row';
            app.lantable.SelectionChangedFcn = createCallbackFcn(app, @lantableSelectionChanged, true);
            app.lantable.Multiselect = 'off';
            app.lanPlotButton = uibutton(app.landing, 'push');
            app.lanPlotButton.ButtonPushedFcn = createCallbackFcn(app, @lanPlotButtonPushed, true);
            app.lanPlotButton.Text = 'Plot T/W vs W/S';
            app.lanplus = uibutton(app.landing, 'push');
            app.lanplus.ButtonPushedFcn = createCallbackFcn(app, @lanplusButtonPushed, true);
            app.lanplus.Text = '+';
            app.lanminus = uibutton(app.landing, 'push');
            app.lanminus.ButtonPushedFcn = createCallbackFcn(app, @lanminusButtonPushed, true);
            app.lanminus.Text = '-';
            app.lanplotenable = uicheckbox(app.landing);
            app.lanplotenable.ValueChangedFcn = createCallbackFcn(app, @lanplotenableValueChanged, true);
            app.lanplotenable.Text = 'Plot Landing Sizing';
            app.lanplotenable.Value = true;
            app.dragpolar = uitab(app.TabGroup);
            app.dragpolar.Title = 'Drag Polars';
            app.dragtab = uitabgroup(app.dragpolar);
            app.dragtab1 = uitab(app.dragtab);
            app.dragtab1.Title = 'Clean';
            app.W5Label_35 = uilabel(app.dragtab1);
            app.W5Label_35.Interpreter = 'latex';
            app.W5Label_35.Text = '$W_{to} \rightarrow S_{wet}$';
            app.dragswet = uieditfield(app.dragtab1, 'text');
            app.dragswet.ValueChangedFcn = createCallbackFcn(app, @dragswetValueChanged, true);
            app.dragswet.Tooltip = {'ft2'};
            app.c_fDropDownLabel = uilabel(app.dragtab1);
            app.c_fDropDownLabel.Interpreter = 'latex';
            app.c_fDropDownLabel.Text = '$c_f$';
            app.dragcf = uidropdown(app.dragtab1);
            app.dragcf.Items = {'0.002', '0.003', '0.004', '0.005', '0.006', '0.007', '0.008', '0.009'};
            app.dragcf.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.dragcf.ValueChangedFcn = createCallbackFcn(app, @dragcfValueChanged, true);
            app.dragcf.Value = '1';
            app.W5Label_36 = uilabel(app.dragtab1);
            app.W5Label_36.Interpreter = 'latex';
            app.W5Label_36.Text = '$f$';
            app.dragf = uieditfield(app.dragtab1, 'text');
            app.dragf.Editable = 'off';
            app.dragf.Tooltip = {'ft2'};
            app.W5Label_37 = uilabel(app.dragtab1);
            app.W5Label_37.Interpreter = 'latex';
            app.W5Label_37.Text = '$S$';
            app.drags = uieditfield(app.dragtab1, 'text');
            app.drags.ValueChangedFcn = createCallbackFcn(app, @dragsValueChanged, true);
            app.drags.Tooltip = {'ft2'};
            app.W5Label_38 = uilabel(app.dragtab1);
            app.W5Label_38.Interpreter = 'latex';
            app.W5Label_38.Text = '$\rightarrow C_{D_0}$';
            app.dragcd0 = uieditfield(app.dragtab1, 'text');
            app.dragcd0.Editable = 'off';
            app.dragcd0.Tooltip = {''};
            app.W5Label_39 = uilabel(app.dragtab1);
            app.W5Label_39.Interpreter = 'latex';
            app.W5Label_39.Text = '$A$';
            app.draga = uieditfield(app.dragtab1, 'text');
            app.draga.ValueChangedFcn = createCallbackFcn(app, @dragaValueChanged, true);
            app.draga.Tooltip = {''};
            app.dragrightarrowLabel = uilabel(app.dragtab1);
            app.dragrightarrowLabel.Interpreter = 'latex';
            app.dragrightarrowLabel.Text = '$\rightarrow$';
            app.W5Label_40 = uilabel(app.dragtab1);
            app.W5Label_40.Interpreter = 'latex';
            app.W5Label_40.Text = '$e$';
            app.drage = uieditfield(app.dragtab1, 'text');
            app.drage.ValueChangedFcn = createCallbackFcn(app, @drageValueChanged, true);
            app.drage.Tooltip = {''};
            app.Tabla36Button1 = uibutton(app.dragtab1, 'push');
            app.Tabla36Button1.ButtonPushedFcn = createCallbackFcn(app, @Tabla36Button1Pushed, true);
            app.Tabla36Button1.Text = 'Tabla 3.6';
            app.W5Label_41 = uilabel(app.dragtab1);
            app.W5Label_41.Interpreter = 'latex';
            app.W5Label_41.Text = '$\frac{W}{S}_{to}$';
            app.dragws = uieditfield(app.dragtab1, 'text');
            app.dragws.Editable = 'off';
            app.dragws.Tooltip = {''};
            app.dragtab2 = uitab(app.dragtab);
            app.dragtab2.Title = 'W/ Flaps & Landing Gear';
            app.dragtable = uitable(app.dragtab2);
            app.dragtable.ColumnName = {'Delta CD0'; 'e'};
            app.dragtable.RowName = {'Take Off Flaps'; 'Landing Flaps'; 'Landing Gear'};
            app.dragtable.ColumnEditable = true;
            app.dragtable.CellEditCallback = createCallbackFcn(app, @dragtableCellEdit, true);
            app.Tabla36Button2 = uibutton(app.dragtab2, 'push');
            app.Tabla36Button2.ButtonPushedFcn = createCallbackFcn(app, @Tabla36Button2Pushed, true);
            app.Tabla36Button2.Text = 'Tabla 3.6';
            app.draglabel1 = uilabel(app.dragpolar);
            app.draglabel1.Interpreter = 'latex';
            app.draglabel1.Text = 'Low Speed, clean';
            app.draglabel2 = uilabel(app.dragpolar);
            app.draglabel2.Interpreter = 'latex';
            app.draglabel2.Text = 'Take-Off, gear up';
            app.draglabel3 = uilabel(app.dragpolar);
            app.draglabel3.Interpreter = 'latex';
            app.draglabel3.Text = 'Take-Off, gear down';
            app.draglabel4 = uilabel(app.dragpolar);
            app.draglabel4.Interpreter = 'latex';
            app.draglabel4.Text = 'Landing, gear up';
            app.draglabel5 = uilabel(app.dragpolar);
            app.draglabel5.Interpreter = 'latex';
            app.draglabel5.Text = 'Landing, gear down';
            app.C_DLabel = uilabel(app.dragpolar);
            app.C_DLabel.Interpreter = 'latex';
            app.C_DLabel.Text = '$C_D =$';
            app.dragv11 = uieditfield(app.dragpolar, 'text');
            app.dragv11.Editable = 'off';
            app.Label_2 = uilabel(app.dragpolar);
            app.Label_2.Interpreter = 'latex';
            app.Label_2.Text = '$+$';
            app.dragv12 = uieditfield(app.dragpolar, 'text');
            app.dragv12.Editable = 'off';
            app.draglabel12 = uilabel(app.dragpolar);
            app.draglabel12.Interpreter = 'latex';
            app.draglabel12.Text = '${C_L}^2$';
            app.C_DLabel_2 = uilabel(app.dragpolar);
            app.C_DLabel_2.Interpreter = 'latex';
            app.C_DLabel_2.Text = '$C_D =$';
            app.dragv21 = uieditfield(app.dragpolar, 'text');
            app.dragv21.Editable = 'off';
            app.Label_3 = uilabel(app.dragpolar);
            app.Label_3.Interpreter = 'latex';
            app.Label_3.Text = '$+$';
            app.dragv22 = uieditfield(app.dragpolar, 'text');
            app.dragv22.Editable = 'off';
            app.draglabel22 = uilabel(app.dragpolar);
            app.draglabel22.Interpreter = 'latex';
            app.draglabel22.Text = '${C_L}^2$';
            app.draglabel32 = uilabel(app.dragpolar);
            app.draglabel32.Interpreter = 'latex';
            app.draglabel32.Text = '${C_L}^2$';
            app.Label_4 = uilabel(app.dragpolar);
            app.Label_4.Interpreter = 'latex';
            app.Label_4.Text = '$+$';
            app.dragv32 = uieditfield(app.dragpolar, 'text');
            app.dragv32.Editable = 'off';
            app.C_DLabel_3 = uilabel(app.dragpolar);
            app.C_DLabel_3.Interpreter = 'latex';
            app.C_DLabel_3.Text = '$C_D =$';
            app.dragv31 = uieditfield(app.dragpolar, 'text');
            app.dragv31.Editable = 'off';
            app.draglabel42 = uilabel(app.dragpolar);
            app.draglabel42.Interpreter = 'latex';
            app.draglabel42.Text = '${C_L}^2$';
            app.Label_5 = uilabel(app.dragpolar);
            app.Label_5.Interpreter = 'latex';
            app.Label_5.Text = '$+$';
            app.dragv42 = uieditfield(app.dragpolar, 'text');
            app.dragv42.Editable = 'off';
            app.C_DLabel_4 = uilabel(app.dragpolar);
            app.C_DLabel_4.Interpreter = 'latex';
            app.C_DLabel_4.Text = '$C_D =$';
            app.dragv41 = uieditfield(app.dragpolar, 'text');
            app.dragv41.Editable = 'off';
            app.draglabel52 = uilabel(app.dragpolar);
            app.draglabel52.Interpreter = 'latex';
            app.draglabel52.Text = '${C_L}^2$';
            app.Label_6 = uilabel(app.dragpolar);
            app.Label_6.Interpreter = 'latex';
            app.Label_6.Text = '$+$';
            app.dragv52 = uieditfield(app.dragpolar, 'text');
            app.dragv52.Editable = 'off';
            app.C_DLabel_5 = uilabel(app.dragpolar);
            app.C_DLabel_5.Interpreter = 'latex';
            app.C_DLabel_5.Text = '$C_D =$';
            app.dragv51 = uieditfield(app.dragpolar, 'text');
            app.dragv51.Editable = 'off';
            app.climbtab = uitab(app.TabGroup);
            app.climbtab.Title = 'Climb Sizing';
            app.ConfiguracinDropDownLabel = uilabel(app.climbtab);
            app.ConfiguracinDropDownLabel.Text = 'Configuración';
            app.climbselect = uidropdown(app.climbtab);
            app.climbselect.Items = {'1'};
            app.climbselect.ValueChangedFcn = createCallbackFcn(app, @climbselectValueChanged, true);
            app.climbselect.Value = '1';
            app.climbplot = uicheckbox(app.climbtab);
            app.climbplot.ValueChangedFcn = createCallbackFcn(app, @climbplotValueChanged, true);
            app.climbplot.Text = 'Plot';
            app.climbplot.Value = true;
            app.climbtakeofflabel = uilabel(app.climbtab);
            app.climbtakeofflabel.Text = 'Take-Off';
            app.climbplus = uibutton(app.climbtab, 'push');
            app.climbplus.ButtonPushedFcn = createCallbackFcn(app, @climbplusButtonPushed, true);
            app.climbplus.Text = '+';
            app.climbminus = uibutton(app.climbtab, 'push');
            app.climbminus.ButtonPushedFcn = createCallbackFcn(app, @climbminusButtonPushed, true);
            app.climbminus.Text = '-';
            app.climblabel1 = uilabel(app.climbtab);
            app.climblabel1.Tooltip = {'Take off climb'; 'gear up, take-off flaps'};
            app.climblabel1.Text = '(OEI) CGR>0.012';
            app.climblabel2 = uilabel(app.climbtab);
            app.climblabel2.Tooltip = {'Take off climb'; 'gear down, take-off flaps'};
            app.climblabel2.Text = '(OEI) CGR>0';
            app.climblabel3 = uilabel(app.climbtab);
            app.climblabel3.Tooltip = {'Take off climb'; 'gear up, take-off flaps'};
            app.climblabel3.Text = '(OEI) CGR>0.024';
            app.climbvs1 = uieditfield(app.climbtab, 'text');
            app.climbvs1.Editable = 'off';
            app.climbvs1.Value = '1.2';
            app.climbvs2 = uieditfield(app.climbtab, 'text');
            app.climbvs2.ValueChangedFcn = createCallbackFcn(app, @climbvs2ValueChanged, true);
            app.climbvs2.Value = '0';
            app.climbvs3 = uieditfield(app.climbtab, 'text');
            app.climbvs3.Editable = 'off';
            app.climbvs3.Value = '1.2';
            app.climbvslabel = uilabel(app.climbtab);
            app.climbvslabel.Interpreter = 'latex';
            app.climbvslabel.Text = '$V_s \cdot$';
            app.climbtable = uitable(app.climbtab);
            app.climbtable.ColumnName = {'cl'; 'cd'; 'LD'; 'TW'; 'plot'};
            app.climbtable.RowName = {};
            app.climbtable.SelectionType = 'row';
            app.climbtable.ColumnEditable = [false false false false true];
            app.climbtable.CellEditCallback = createCallbackFcn(app, @climbtableCellEdit, true);
            app.climbtable.Multiselect = 'off';
            app.climbvs4 = uieditfield(app.climbtab, 'text');
            app.climbvs4.Editable = 'off';
            app.climbvs4.Value = '1.25';
            app.climbvs5 = uieditfield(app.climbtab, 'text');
            app.climbvs5.Editable = 'off';
            app.climbvs5.Value = '1.3';
            app.climbvs6 = uieditfield(app.climbtab, 'text');
            app.climbvs6.Editable = 'off';
            app.climbvs6.Value = '1.5';
            app.climblabel4 = uilabel(app.climbtab);
            app.climblabel4.Tooltip = {'En route'; 'gear up, flaps up'};
            app.climblabel4.Text = '(OEI) CGR>0.012';
            app.climbenroutelabel = uilabel(app.climbtab);
            app.climbenroutelabel.Text = 'En route';
            app.climblandinglabel = uilabel(app.climbtab);
            app.climblandinglabel.Text = 'Landing';
            app.climblabel5 = uilabel(app.climbtab);
            app.climblabel5.Tooltip = {'Landing climb'; 'gear down, landing flaps'};
            app.climblabel5.Text = '(AEO) CGR>0.032';
            app.climblabel6 = uilabel(app.climbtab);
            app.climblabel6.Tooltip = {'Landing climb'; 'gear down, approach flaps'};
            app.climblabel6.Text = '(OEI) CGR>0.021';
            app.C_L_tomaxLabel = uilabel(app.climbtab);
            app.C_L_tomaxLabel.Interpreter = 'latex';
            app.C_L_tomaxLabel.Text = '$C_{L_{to,max}}$';
            app.climbtocl = uieditfield(app.climbtab, 'text');
            app.climbtocl.ValueChangedFcn = createCallbackFcn(app, @climbtoclValueChanged, true);
            app.climbtocl.Value = '0';
            app.C_L_tomaxLabel_2 = uilabel(app.climbtab);
            app.C_L_tomaxLabel_2.Interpreter = 'latex';
            app.C_L_tomaxLabel_2.Text = '$C_{L_{max}}$';
            app.climbercl = uieditfield(app.climbtab, 'text');
            app.climbercl.ValueChangedFcn = createCallbackFcn(app, @climberclValueChanged, true);
            app.climbercl.Value = '0';
            app.C_L_tomaxLabel_3 = uilabel(app.climbtab);
            app.C_L_tomaxLabel_3.Interpreter = 'latex';
            app.C_L_tomaxLabel_3.Text = '$C_{L_{L,max}}$';
            app.climblancl = uieditfield(app.climbtab, 'text');
            app.climblancl.ValueChangedFcn = createCallbackFcn(app, @climblanclValueChanged, true);
            app.climblancl.Value = '0';
            app.climbPlotButton = uibutton(app.climbtab, 'push');
            app.climbPlotButton.ButtonPushedFcn = createCallbackFcn(app, @climbPlotButtonPushed, true);
            app.climbPlotButton.Text = 'Plot T/W vs W/S';
            app.climbplotenable = uicheckbox(app.climbtab);
            app.climbplotenable.ValueChangedFcn = createCallbackFcn(app, @climbplotenableValueChanged, true);
            app.climbplotenable.Text = 'Plot Climb Sizing';
            app.climbplotenable.Value = true;
            app.C_L_tomaxLabel_4 = uilabel(app.climbtab);
            app.C_L_tomaxLabel_4.Interpreter = 'latex';
            app.C_L_tomaxLabel_4.Text = '$W_L=W_{to}$';
            app.climbwlwto = uieditfield(app.climbtab, 'text');
            app.climbwlwto.ValueChangedFcn = createCallbackFcn(app, @climbwlwtoValueChanged, true);
            app.climbwlwto.Value = '0';
            app.climbapproachlabel = uilabel(app.climbtab);
            app.climbapproachlabel.Text = 'Approach';
            app.C_L_tomaxLabel_5 = uilabel(app.climbtab);
            app.C_L_tomaxLabel_5.Interpreter = 'latex';
            app.C_L_tomaxLabel_5.Text = '$C_{L_{A,max}}$';
            app.climbappcl = uieditfield(app.climbtab, 'text');
            app.climbappcl.ValueChangedFcn = createCallbackFcn(app, @climbappclValueChanged, true);
            app.climbappcl.Value = '0';
            app.save = uibutton(app.UIFigure, 'push');
            app.save.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.save.Text = 'Guardar';
            app.load = uibutton(app.UIFigure, 'push');
            app.load.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.load.Text = 'Cargar';
            app.filename = uilabel(app.UIFigure);
            app.filename.Text = 'Ningún archivo cargado';
            app.save_new = uibutton(app.UIFigure, 'push');
            app.save_new.ButtonPushedFcn = createCallbackFcn(app, @save_newButtonPushed, true);
            app.save_new.Text = 'Guardar nuevo';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = roskam

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end